classdef Test_Servo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Servo1KnobLabel        matlab.ui.control.Label
        Servo1Knob             matlab.ui.control.Knob
        Servo2KnobLabel        matlab.ui.control.Label
        Servo2Knob             matlab.ui.control.Knob
        Switch                 matlab.ui.control.RockerSwitch
        Lamp                   matlab.ui.control.Lamp
        Servo1Button           matlab.ui.control.Button
        Servo2Button           matlab.ui.control.Button
        Lamp_2                 matlab.ui.control.Lamp
        DeitirButton           matlab.ui.control.Button
        TextArea               matlab.ui.control.TextArea
        PortDropDownLabel      matlab.ui.control.Label
        PortDropDown           matlab.ui.control.DropDown
        BaudrateDropDownLabel  matlab.ui.control.Label
        BaudrateDropDown       matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        Renk;
        s;
        Ports;
        baud;
        Servo1_angle;
        Servo2_angle;
    end
    
    methods (Access = private)
        
        function set_Renk(app,btn)
            switch(btn)
                case 1
                    app.Lamp.Color = 'g';
                    app.Lamp_2.Color = 'r';
                case 2
                    app.Lamp_2.Color = 'g';
                    app.Lamp.Color = 'r';
                case 3
                    app.Lamp.Color = 'r';
                    app.Lamp_2.Color = 'r';
            end     
        end
        function write(app,w)
            fprintf(app.s,'%f',w);
        end
        function open(app)
            app.s = serial(app.Ports,"BaudRate",app.baud);
            app.s.InputBufferSize = 120;
            app.s.OutputBufferSize = 100;
            if strcmpi(app.s.Status,'closed')
                fopen(app.s);
            end          
        end
        function close(app)
            if ~isempty(app.s)
                if strcmp(app.s.Status,'open')
                    fclose(app.s);
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            delete(instrfind)
            app.Ports = seriallist;
            if ~isempty(app.Ports)
                app.PortDropDown.Value = app.Ports;
            end

%             if length(app.Ports)
%                 app.s = serial(app.Ports,"BaudRate",app.baud);
%             end
            
        end

        % Button pushed function: Servo1Button
        function Servo1ButtonPushed(app, event)
            app.set_Renk(1);
            
            %app.TextArea.Value = r;
            flag = true;
            fprintf(app.s,'%s','a');
            bytes = app.s.BytesAvailable;
            while flag
            if bytes>0
               r = fscanf(app.s);
               app.TextArea.Value = r;
               flag = false;
            else
                app.TextArea.Value = "Tekrar Gönderiliyor";
                fprintf(app.s,'%s','a');
                bytes = app.s.BytesAvailable;
                pause(1);
            end
            end
        end

        % Button pushed function: Servo2Button
        function Servo2ButtonPushed(app, event)
            app.set_Renk(2);
                        flag = true;
            fprintf(app.s,'%s','b');
            bytes = app.s.BytesAvailable;
            while flag
            if bytes>0
               r = fscanf(app.s);
               app.TextArea.Value = r;
               flag = false;
            else
                app.TextArea.Value = "Tekrar Gönderiliyor";
                fprintf(app.s,'%s','b');
                bytes = app.s.BytesAvailable;
                pause(1);
            end
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            app.TextArea.Value = value;
            app.baud = str2double(app.BaudrateDropDown.Value);
            if strcmp(value,'On') && ~isempty(app.Ports) 
                app.open();
            elseif strcmp(value,'Off') && ~isempty(app.Ports)
                app.close();
            end    
        end

        % Value changing function: Servo1Knob
        function Servo1KnobValueChanging(app, event)
            app.Servo1_angle = event.Value;
            app.write(app.Servo1_angle);

        end

        % Value changing function: Servo2Knob
        function Servo2KnobValueChanging(app, event)
            app.Servo2_angle = event.Value;
            app.write(app.Servo2_angle);
        end

        % Button pushed function: DeitirButton
        function DeitirButtonPushed(app, event)
            app.write(181);
            app.TextArea.Value = "Servo Değiştirilmeye Hazır.";
            app.set_Renk(3);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Servo1KnobLabel = uilabel(app.UIFigure);
            app.Servo1KnobLabel.Text = 'Servo1';
            app.Servo1Knob = uiknob(app.UIFigure, 'continuous');
            app.Servo1Knob.Limits = [0 180];
            app.Servo1Knob.ValueChangingFcn = createCallbackFcn(app, @Servo1KnobValueChanging, true);
            app.Servo2KnobLabel = uilabel(app.UIFigure);
            app.Servo2KnobLabel.Text = 'Servo2';
            app.Servo2Knob = uiknob(app.UIFigure, 'continuous');
            app.Servo2Knob.Limits = [0 180];
            app.Servo2Knob.ValueChangingFcn = createCallbackFcn(app, @Servo2KnobValueChanging, true);
            app.Switch = uiswitch(app.UIFigure, 'rocker');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Lamp = uilamp(app.UIFigure);
            app.Servo1Button = uibutton(app.UIFigure, 'push');
            app.Servo1Button.ButtonPushedFcn = createCallbackFcn(app, @Servo1ButtonPushed, true);
            app.Servo1Button.Text = 'Servo1';
            app.Servo2Button = uibutton(app.UIFigure, 'push');
            app.Servo2Button.ButtonPushedFcn = createCallbackFcn(app, @Servo2ButtonPushed, true);
            app.Servo2Button.Text = 'Servo2';
            app.Lamp_2 = uilamp(app.UIFigure);
            app.DeitirButton = uibutton(app.UIFigure, 'push');
            app.DeitirButton.ButtonPushedFcn = createCallbackFcn(app, @DeitirButtonPushed, true);
            app.DeitirButton.Text = 'Değiştir';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {''; ''};
            app.PortDropDownLabel = uilabel(app.UIFigure);
            app.PortDropDownLabel.Text = 'Port:';
            app.PortDropDown = uidropdown(app.UIFigure);
            app.PortDropDown.Items = {'COM3', 'COM4', 'COM5', 'COM6', 'COM7'};
            app.PortDropDown.Value = 'COM3';
            app.BaudrateDropDownLabel = uilabel(app.UIFigure);
            app.BaudrateDropDownLabel.Text = 'Baudrate:';
            app.BaudrateDropDown = uidropdown(app.UIFigure);
            app.BaudrateDropDown.Items = {'9600', '34800', '115200', ''};
            app.BaudrateDropDown.Value = '9600';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Test_Servo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end